classdef ENGR131phase3lab3 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        valueofdtLabel                matlab.ui.control.Label
        dtLabel                       matlab.ui.control.Label
        valueofFrequencyLabel         matlab.ui.control.Label
        FrequencyLabel                matlab.ui.control.Label
        LPFilterCutoffEditField       matlab.ui.control.NumericEditField
        LPFilterCutoffEditFieldLabel  matlab.ui.control.Label
        FilterOrderSlider             matlab.ui.control.Slider
        FilterOrderSliderLabel        matlab.ui.control.Label
        PlottoShowButtonGroup         matlab.ui.container.ButtonGroup
        FilteredButton                matlab.ui.control.RadioButton
        FFTButton                     matlab.ui.control.RadioButton
        RawButton                     matlab.ui.control.RadioButton
        PlotDataButton                matlab.ui.control.Button
        Lamp                          matlab.ui.control.Lamp
        RecordingTimeProgressGauge    matlab.ui.control.Gauge
        RecordingTimeProgressLabel    matlab.ui.control.Label
        StartRecordingButton          matlab.ui.control.Button
        RecordingTime                 matlab.ui.control.DiscreteKnob
        RecordingTimeKnobLabel        matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ArduinoStuff;
        
        SerialData; 
    end
    
    properties (Access = public)
        RecordedData
        F %Lab303 Frequency 
        dt %derivative of t / average change of t
    end

    methods (Access = private)
        function LineColor = LineColor(app,ColorInput)
            switch ColorInput
                case "Black"
                    LineColor = 'k-';
                case "Red"
                    LineColor = 'r-';
                case "Blue"
                    LineColor = 'b-';
                case "Yellow"
                    LineColor = 'y-';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialData = serialport("/dev/cu.usbmodem14101",9600);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clearvars
        end

        % Callback function: StartRecordingButton, UIFigure
        function UIFigureButtonDown(app, event)
            %REVISING CALLBACK FUNCTION FOR RECORD BUTTON (Lab 3 Phase 3 Filtering
            %Data)
            
            % Records Data From Arduino for set recording time
            app.Lamp.Color = 'green';
            RecTime = str2num(app.RecordingTime.Value);
            Timeset = 0;
            counter = 1;   
            RecData = [];
            serialobj = app.SerialData;
            tic
            TimeVec = [];
            while toc <= RecTime
                TimeVec = [TimeVec; toc];
                Data = readline(serialobj);
  %% UNMUTE %RecData(counter,:) = str2num(char(Data));
                counter = counter + 1;
                %drawnow limitrate
                app.RecordingTimeProgressGauge.Value = (toc/RecTime)*100;
            end
            %V = length(RecData)/RecTime;
            %TimeVec = linspace(0,RecTime,V);
            
            times = RecData(:);  %% ORIGINAL = times = RecData(:,2)
            %computing dt  
            dt = times - circshift(times,1);   %create differences between times
                                               % t(n+1) - t(n)                                            
  %% UNMUTE %dt(0) = dt(1) %override the first element of dt (to equal second element)
            dt = mean(dt) %takes average of of time differences 
            F = 1/dt %frequency = 1/dt
            
  %% UNMUTE %app.valueofFrequencyLabel.text = F %saving F as app property
  %% UNMUTE %app.valueofdtLabel.text = dt %saving dt as app property
            app.F = F; %takes our local variable (F) and records it to a global
                      % variable accessible everywhere
            app.dt = dt;
            
            
            app.Lamp.Color = 'red';
            pause(3)
            app.Lamp.Color = 'black';
            app.RecordingTimeProgressGauge.Value = 0;
 
            RecData = [TimeVec RecData];
            app.RecordedData = RecData;
            clear serialobj
            
            %app.CumulativeNumberOfRecordingsGauge.Value = app.CumulativeNumberOfRecordingsGauge.Value + 1;


        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            %% REVISING FOR LAB 3 PHASE 3 FILTERING DATA
            
            if app.RawButton.Value == true
                %plot the raw data (B 1)
                
                %plot(app.UIAxes,app.RecordedData(:,2),app.RecordedData(:,3),red);
                %plot(app.UIAxes,app.RecordedData(:,2),app.RecordedData(:,4),green);
            end
            
            if app.FFTButton.Value == true
                %plot the FFT data (B 2)
                humidityfft = myfft(app.RecordedData(:,3)); %calling myfft function from external script
                temperaturefft = myfft(app.RecordedData(:,4));
                n = length(app.RecordedData(:,3)); %both columns should be same length so 3 or 4
                fshift = (-n/2:n/2-1)*(fs/n); %produces a vector of frequencies for plotting
                plot(app.UIAxes,fshift,humidityfft,red); %humidityfft is data
                plot(app.UIAxes,fshift,temperaturefft,green);
            end
            
            if app.FilteredButton.Value == true
                %plot the filtered data (B 3)
                
                humidityfilter = myfilter(app.RecordedData(:,3)); %calling myfilter function from external script
                temperaturefilter = myfilter(app.RecordedData(:,4)); %filtering app.RecordedData(:,4) column (temperature column)
                
                plot(app.UIAxes,app.RecordedData(:,2),humidityfilter,red);
                plot(app.UIAxes,app.RecordedData(:,2),temperaturefilter,green);
            end
            
            %{
            Markerinput = app.PlotwithmarkersEditField.Value;
            CheckMarkers = contains(Markerinput,"y","IgnoreCase",true);
            if CheckMarkers == 1
                TempMarker = 'o';
                HumMarker = 's';
            else
                TempMarker = 'none';
                HumMarker = 'none';
            end
            
            Colorinput1 = app.LineColorForTemperatureDropDown.Value;
            Colorinput2 = app.LineColorForHumidityDropDown.Value;

            TempLineColor = LineColor(app,Colorinput1);
            TempLineColor = char(TempLineColor);
            HumLineColor = LineColor(app,Colorinput2);
            HumLineColor = char(HumLineColor);


            PlotTempVal = app.PlotTemperatureSwitch.Value;
            if contains(PlotTempVal,"On") == 1
                plot(app.UIAxes,app.RecordedData(:,2),app.RecordedData(:,1),TempLineColor,TempMarker)
            end
            
            PlotHumVal = app.PlotHumiditySwitch.Value;
            
            if contains(PlotHumVal,"On") == 1
                plot(app.UIAxes,app.RecordedData(:,3),app.RecordedData(:,2),HumLineColor,HumMarker)
            end
            %}
            
            %hold(app.UIAxes,'on')
         
            %ylim(app.UIAxes,[0 60])
            hold off
            
        end

        % Callback function
        function SaveToExcelButtonPushed(app, event)
            
            Labels = ["Temperature" , "Humidity" , "Time"];
            xlswrite('Temerature_and_Humidity_Data.xlsx',Labels,'Sheet 1','B1:D1')
            xlsWrite('Temerature_and_Humidity_Data.xlsx',app.RecordedData(:,1),'Sheet 1','B1:B100')
            xlsWrite('Temerature_and_Humidity_Data.xlsx',app.RecordedData(:,2),'Sheet 1','C1:C100')
            xlsWrite('Temerature_and_Humidity_Data.xlsx',app.RecordedData(:,3),'Sheet 1','D1:D100')
            
        end

        % Value changed function: LPFilterCutoffEditField
        function LPFilterCutoffEditFieldValueChanged(app, event)
            value = app.LPFilterCutoffEditField.Value;
            
            global cutOffFreq %creating a global variable to access when plotting
            cutOffFreq = value;
        end
        end

        % Value changed function: FilterOrderSlider
        function FilterOrderSliderValueChanged(app, event)
            value = app.FilterOrderSlider.Value;
             global filterOrder %creating a global variable to access when plotting
            filterOrder = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Recorded Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature and Humidity')
            zlabel(app.UIAxes, 'Z')
            app.RecordingTimeKnobLabel = uilabel(app.UIFigure);
            app.RecordingTimeKnobLabel.Text = 'Recording Time';
            app.RecordingTime = uiknob(app.UIFigure, 'discrete');
            app.RecordingTime.Items = {'5', '10', '15', '20'};
            app.RecordingTime.Value = '15';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.RecordingTimeProgressLabel = uilabel(app.UIFigure);
            app.RecordingTimeProgressLabel.Text = 'Recording Time Progress';
            app.RecordingTimeProgressGauge = uigauge(app.UIFigure, 'circular');
            app.Lamp = uilamp(app.UIFigure);
            app.PlotDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.PlottoShowButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottoShowButtonGroup.Title = 'Plot to Show';
            app.RawButton = uiradiobutton(app.PlottoShowButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.FFTButton = uiradiobutton(app.PlottoShowButtonGroup);
            app.FFTButton.Text = 'FFT';
            app.FilteredButton = uiradiobutton(app.PlottoShowButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.FilterOrderSliderLabel = uilabel(app.UIFigure);
            app.FilterOrderSliderLabel.Text = 'Filter Order';
            app.FilterOrderSlider = uislider(app.UIFigure);
            app.FilterOrderSlider.Limits = [1 5];
            app.FilterOrderSlider.ValueChangedFcn = createCallbackFcn(app, @FilterOrderSliderValueChanged, true);
            app.FilterOrderSlider.Value = 1;
            app.LPFilterCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LPFilterCutoffEditFieldLabel.Text = 'LP Filter Cutoff';
            app.LPFilterCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.LPFilterCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @LPFilterCutoffEditFieldValueChanged, true);
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.valueofFrequencyLabel = uilabel(app.UIFigure);
            app.valueofFrequencyLabel.Text = '(value of Frequency)';
            app.dtLabel = uilabel(app.UIFigure);
            app.dtLabel.Text = 'dt';
            app.valueofdtLabel = uilabel(app.UIFigure);
            app.valueofdtLabel.Text = '(value of dt)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENGR131phase3lab3

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end