classdef ENGR131FINAL < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        FileLoadLabel                   matlab.ui.control.Label
        LoadExcelDataFileNameEditField  matlab.ui.control.EditField
        LoadExcelDataFileNameEditFieldLabel  matlab.ui.control.Label
        SaveDatatoExcelButton           matlab.ui.control.Button
        AnalyzeDataButton               matlab.ui.control.Button
        UITable                         matlab.ui.control.Table
        ServoMotorAnglewillmovetopositionKnob  matlab.ui.control.Knob
        ServoMotorAnglewillmovetopositionKnobLabel  matlab.ui.control.Label
        valueofdtLabel                  matlab.ui.control.Label
        dtLabel                         matlab.ui.control.Label
        valueofFrequencyLabel           matlab.ui.control.Label
        FrequencyLabel                  matlab.ui.control.Label
        LPNormalizedCutoffEditField     matlab.ui.control.NumericEditField
        LPNormalizedCutoffEditFieldLabel  matlab.ui.control.Label
        FilterOrderSlider               matlab.ui.control.Slider
        FilterOrderSliderLabel          matlab.ui.control.Label
        PlottoShowButtonGroup           matlab.ui.container.ButtonGroup
        FilteredButton                  matlab.ui.control.RadioButton
        FFTButton                       matlab.ui.control.RadioButton
        RawButton                       matlab.ui.control.RadioButton
        PlotDataButton                  matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        RecordingTimeProgressGauge      matlab.ui.control.Gauge
        RecordingTimeProgressLabel      matlab.ui.control.Label
        StartRecordingButton            matlab.ui.control.Button
        RecordingTime                   matlab.ui.control.DiscreteKnob
        RecordingTimeKnobLabel          matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ArduinoStuff;
        
        SerialData; 
    end
    
    properties (Access = public)
        RecordedData
        F %Lab303 Frequency 
        dt %derivative of t / average change of t
        filterOrder
        cutOffFreq
        time %declaring time in miliseconds (1st column of arduino data) as global variable
        light %declaring light as global variable
        temperature %declaring temperature as global variable
        humidity  %declaring humidity as global variable
    end

    methods (Access = private)
        function LineColor = LineColor(app,ColorInput)
            switch ColorInput
                case "Black"
                    LineColor = 'k-';
                case "Red"
                    LineColor = 'r-';
                case "Blue"
                    LineColor = 'b-';
                case "Yellow"
                    LineColor = 'y-';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialData = serialport("/dev/cu.usbmodem14101",9600);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clearvars
        end

        % Callback function: StartRecordingButton, UIFigure
        function UIFigureButtonDown(app, event)
              %REVISING CALLBACK FUNCTION FOR RECORD BUTTON (Lab 3 Phase 3 Filtering
            %Data)
            % Recording Data From Arduino for set recording time 
            % letting arduino know to display data (type d in command window)
            serialobj = app.SerialData;
            
            txtvalue = "d";
            write(serialobj,txtvalue,"char");
            pause(.5);
            
            app.Lamp.Color = 'green';
            RecTime = str2num(app.RecordingTime.Value);
            Timeset = 0;
            counter = 1;   
            RecData = [];
            Data = [];
            serialobj = app.SerialData;
            tic
            TimeVec = [];
            time = []; %time in miliseconds (1st column of arduino data)
            light = []; %second column
            temperature = []; %third column
            humidity = []; %fourth column
            while toc <= RecTime
                TimeVec = [TimeVec; toc];
                data = strsplit(readline(serialobj)); %reads line
                
                assignin('base','data',data);
                
                
                time = [time; str2num(data(1))]; %grabbing time
                light = [light; str2num(data(2))]; %grabbing light intensity
                temperature = [temperature; str2num(data(3))]; %grabbing temperature
                humidity = [humidity; str2num(data(4))]; %grabbing humidity
                
                
                %RecData(counter,:) = str2num(char(Data));
                counter = counter + 1;
                %drawnow limitrate
                app.RecordingTimeProgressGauge.Value = (toc/RecTime)*100;
            end
         
            txtvalue = "e"; %telling arduino to end (type e in arduino command window)
            write(serialobj,txtvalue,"char");

            
            %computing dt  
            dt = time - circshift(time,1);   %create differences between times
                                               % t(n+1) - t(n)                                            
            % dt(0) = dt(1); %override the first element of dt (to equal second element)
            dt = mean(dt); %takes average of of time differences 
            dt = abs(dt)
            F = 1/dt %frequency = 1/dt
            
            app.time = time; %send time value to global variable
            app.light = light; %send time value to global variable
            app.temperature = temperature; %send time value to global variable
            app.humidity = humidity; %send time value to global variable
           
            
            app.valueofFrequencyLabel.Text = num2str(F,'%10.2e\n') %saving F as app property
            app.valueofdtLabel.Text = num2str(dt,'%10.2e\n') %saving dt as app property
            app.F = F; %takes our local variable (F) and records it to a global
                      % variable accessible everywhere
            app.dt = app.dt;
            app.cutOffFreq = 0.5;
            app.filterOrder = 1;
            
            app.Lamp.Color = 'red';
            pause(3)
            app.Lamp.Color = 'black';
            app.RecordingTimeProgressGauge.Value = 0;
 
            RecData = [TimeVec Data];
            app.RecordedData = RecData;
            
            %assignin('base','app.time',app.time);
            %assignin('base','app.light',app.light);
            %assignin('base','app.temperature',app.temperature);
            %assignin('base','app.humidity',app.humidity);
            assignin('base','time',time);
            assignin('base','light',light);
            assignin('base','temperature',temperature);
            assignin('base','humidity',humidity);
            
            
            %{
            assignin('base','RecData',RecData);
            assignin('base','TimeVec',TimeVec);
            assignin('base','time',times);
            assignin('base','RecTime',RecTime);
            assignin('base','Data',Data);
            assignin('base','filterOrder',app.filterOrder);
            assignin('base','cutOffFreq',app.cutOffFreq);
            %}


            clear serialobj

        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            % REVISING FOR LAB 3 PHASE 3 FILTERING DATA
            if app.RawButton.Value == true
                
                %plot the raw data (B 1)
                plot(app.UIAxes,app.time,app.light,'r');
                hold(app.UIAxes)
                plot(app.UIAxes,app.time,app.temperature,'b');
                hold(app.UIAxes)
                %plot(app.UIAxes,app.time,app.humidity,'g');
                %hold(app.UIAxes)
            end
            if app.FFTButton.Value == true
                %plot the FFT data (B 2)
                photofft = myfft(app.RecordedData(:,2)); %calling myfft function from external script    
                n = length(app.RecordedData(:,2)); %both columns should be same length so 1 or 2
                fshift = (-n/2:n/2-1)*(app.F/n); %produces a vector of frequencies for plotting
                plot(app.UIAxes,fshift,photofft,'r'); %plotting data of the fast fourier transformation function
            end
            if app.FilteredButton.Value == true
                %plot the filtered data (B 3)
                
                photofilter = myfilter(app.RecordedData(:,2),app.filterOrder,app.cutOffFreq); %calling myfilter function from external script 
                plot(app.UIAxes,app.RecordedData(:,1),photofilter,'r');
            end       
        end

        % Button pushed function: SaveDatatoExcelButton
        function SaveToExcelButtonPushed(app, event)
            
            Labels = ["Time", "Light", "Humidity","Temperatue"];
            xlswrite('Recorded_Data.xlsx',Labels,'Sheet 1','B1:E1')
            xlsWrite('Recorded_Data.xlsx',app.time,'Sheet 1','B1:B100')
            xlsWrite('Recorded_Data.xlsx',app.light,'Sheet 1','C1:C100')
            xlsWrite('Recorded_Data.xlsx',app.humidity,'Sheet 1','D1:D100')
            xlsWrite('Recorded_Data.xlsx',app.temperature,'Sheet 1','D1:D100')
            
        end

        % Value changed function: LPNormalizedCutoffEditField
        function LPNormalizedCutoffEditFieldValueChanged(app, event)
            value = app.LPNormalizedCutoffEditField.Value;
                
            app.cutOffFreq = abs(value);
        end

        % Value changed function: FilterOrderSlider
        function FilterOrderSliderValueChanged(app, event)
            value = app.FilterOrderSlider.Value;
            app.filterOrder = round(value);
        end

        % Value changed function: 
        % ServoMotorAnglewillmovetopositionKnob
        function ServoMotorAnglewillmovetopositionKnobValueChanged(app, event)
            value = app.ServoMotorAnglewillmovetopositionKnob.Value;
            %knob needs to stop for servo to change
            serialobj = app.SerialData;
            
            txtvalue = "s"+num2str(value)+":";
            write(serialobj,txtvalue,"char");
        end

        % Button pushed function: AnalyzeDataButton
        function AnalyzeDataButtonPushed(app, event)
            DataArray = [mean(app.light), mean(app.humidity), mean(app.temperature) ; std(app.light), std(app.humidity), std(app.temperature) ; min(app.light), min(app.humidity), min(app.temperature) ; max(app.light), max(app.humidity), max(app.temperature)];
            app.UITable.Data = DataArray;
        end

        % Callback function
        function LoadExcelDataButtonPushed(app, event)
            % Load Raw Excel Data File
            fid = fopen(app.LoadExcelDataFileNameEditField.Value);
            if fid == -1
                app.FileLoadLabel.Text = 'File Failed to Load';
            else
                app.FileLoadLabel.Text = "File Loaded Successfully";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Recorded Data')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Light (r), Temperature (b), & Humidity (g)')
            zlabel(app.UIAxes, 'Z')
            app.RecordingTimeKnobLabel = uilabel(app.UIFigure);
            app.RecordingTimeKnobLabel.Text = 'Recording Time';
            app.RecordingTime = uiknob(app.UIFigure, 'discrete');
            app.RecordingTime.Items = {'5', '10', '15', '20'};
            app.RecordingTime.Value = '15';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.RecordingTimeProgressLabel = uilabel(app.UIFigure);
            app.RecordingTimeProgressLabel.Text = 'Recording Time Progress';
            app.RecordingTimeProgressGauge = uigauge(app.UIFigure, 'circular');
            app.Lamp = uilamp(app.UIFigure);
            app.PlotDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.PlottoShowButtonGroup = uibuttongroup(app.UIFigure);
            app.PlottoShowButtonGroup.Title = 'Plot to Show';
            app.RawButton = uiradiobutton(app.PlottoShowButtonGroup);
            app.RawButton.Text = 'Raw';
            app.RawButton.Value = true;
            app.FFTButton = uiradiobutton(app.PlottoShowButtonGroup);
            app.FFTButton.Text = 'FFT';
            app.FilteredButton = uiradiobutton(app.PlottoShowButtonGroup);
            app.FilteredButton.Text = 'Filtered';
            app.FilterOrderSliderLabel = uilabel(app.UIFigure);
            app.FilterOrderSliderLabel.Text = 'Filter Order';
            app.FilterOrderSlider = uislider(app.UIFigure);
            app.FilterOrderSlider.Limits = [1 5];
            app.FilterOrderSlider.MajorTicks = [1 2 3 4 5];
            app.FilterOrderSlider.ValueChangedFcn = createCallbackFcn(app, @FilterOrderSliderValueChanged, true);
            app.FilterOrderSlider.MinorTicks = [1 2 3 4 5];
            app.FilterOrderSlider.Value = 1;
            app.LPNormalizedCutoffEditFieldLabel = uilabel(app.UIFigure);
            app.LPNormalizedCutoffEditFieldLabel.Text = 'LP Normalized Cutoff';
            app.LPNormalizedCutoffEditField = uieditfield(app.UIFigure, 'numeric');
            app.LPNormalizedCutoffEditField.ValueChangedFcn = createCallbackFcn(app, @LPNormalizedCutoffEditFieldValueChanged, true);
            app.FrequencyLabel = uilabel(app.UIFigure);
            app.FrequencyLabel.Text = 'Frequency';
            app.valueofFrequencyLabel = uilabel(app.UIFigure);
            app.valueofFrequencyLabel.Text = '(value of Frequency)';
            app.dtLabel = uilabel(app.UIFigure);
            app.dtLabel.Text = 'dt';
            app.valueofdtLabel = uilabel(app.UIFigure);
            app.valueofdtLabel.Text = '(value of dt)';
            app.ServoMotorAnglewillmovetopositionKnobLabel = uilabel(app.UIFigure);
            app.ServoMotorAnglewillmovetopositionKnobLabel.Text = 'Servo Motor Angle (will move to position)';
            app.ServoMotorAnglewillmovetopositionKnob = uiknob(app.UIFigure, 'continuous');
            app.ServoMotorAnglewillmovetopositionKnob.Limits = [0 180];
            app.ServoMotorAnglewillmovetopositionKnob.ValueChangedFcn = createCallbackFcn(app, @ServoMotorAnglewillmovetopositionKnobValueChanged, true);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Light'; 'Humidity'; 'Temperature'};
            app.UITable.RowName = {'Mean'; 'Standard Deviation'; 'Min'; 'Max'};
            app.AnalyzeDataButton = uibutton(app.UIFigure, 'push');
            app.AnalyzeDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeDataButtonPushed, true);
            app.AnalyzeDataButton.Text = 'Analyze Data';
            app.SaveDatatoExcelButton = uibutton(app.UIFigure, 'push');
            app.SaveDatatoExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToExcelButtonPushed, true);
            app.SaveDatatoExcelButton.Text = 'Save Data to Excel';
            app.LoadExcelDataFileNameEditFieldLabel = uilabel(app.UIFigure);
            app.LoadExcelDataFileNameEditFieldLabel.Text = 'Load Excel Data: File Name';
            app.LoadExcelDataFileNameEditField = uieditfield(app.UIFigure, 'text');
            app.FileLoadLabel = uilabel(app.UIFigure);
            app.FileLoadLabel.Text = '(File Load)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENGR131FINAL

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end