classdef ENGR131phase3lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        LineColorForHumidityDropDown   matlab.ui.control.DropDown
        LineColorForHumidityLabel      matlab.ui.control.Label
        LineColorForTemperatureDropDown  matlab.ui.control.DropDown
        LineColorForTemperatureDropDownLabel  matlab.ui.control.Label
        SaveToExcelButton              matlab.ui.control.Button
        PlotwithmarkersEditField       matlab.ui.control.EditField
        PlotwithmarkersEditFieldLabel  matlab.ui.control.Label
        PlotDataButton                 matlab.ui.control.Button
        PlotTemperatureSwitch          matlab.ui.control.Switch
        PlotTemperatureSwitchLabel     matlab.ui.control.Label
        PlotHumiditySwitch             matlab.ui.control.Switch
        PlotHumiditySwitchLabel        matlab.ui.control.Label
        CumulativeNumberOfRecordingsGauge  matlab.ui.control.Gauge
        CumulativeNumberOfRecordingsGaugeLabel  matlab.ui.control.Label
        Lamp                           matlab.ui.control.Lamp
        RecordingTimeProgressGauge     matlab.ui.control.Gauge
        RecordingTimeProgressLabel     matlab.ui.control.Label
        StartRecordingButton           matlab.ui.control.Button
        RecordingTime                  matlab.ui.control.DiscreteKnob
        RecordingTimeKnobLabel         matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        ArduinoStuff % Description
        RecordedData % Description
        SerialData % Description
    end
    
    methods (Access = private)
        function LineColor = LineColor(app,ColorInput)
            switch ColorInput
                case "Black"
                    LineColor = 'k-';
                case "Red"
                    LineColor = 'r-';
                case "Blue"
                    LineColor = 'b-';
                case "Yellow"
                    LineColor = 'y-';
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.SerialData = serialport("/dev/cu.usbmodem14101",9600);
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
            clearvars
        end

        % Callback function: StartRecordingButton, UIFigure
        function UIFigureButtonDown(app, event)
            
             % Records Data From Arduino for set recording time
            app.Lamp.Color = 'green';
            RecTime = str2num(app.RecordingTime.Value);
            Timeset = 0;
            counter = 1;   
            RecData = [];
            serialobj = app.SerialData;
            tic
            TimeVec = [];
            while toc <= RecTime
                TimeVec = [TimeVec; toc];
                Data = readline(serialobj);
                RecData(counter,:) = str2num(char(Data));
                counter = counter + 1;
                drawnow limitrate
                app.RecordingTimeProgressGauge.Value = (toc/RecTime)*100;
            end
            %V = length(RecData)/RecTime;
            %TimeVec = linspace(0,RecTime,V);
            app.Lamp.Color = 'red';
            pause(3)
            app.Lamp.Color = 'black';
            app.RecordingTimeProgressGauge.Value = 0;
 
            RecData = [TimeVec RecData];
            app.RecordedData = RecData;
            clear serialobj
            
            app.CumulativeNumberOfRecordingsGauge.Value = app.CumulativeNumberOfRecordingsGauge.Value + 1;
           

        end

        % Button pushed function: PlotDataButton
        function PlotDataButtonPushed(app, event)
            Markerinput = app.PlotwithmarkersEditField.Value;
            CheckMarkers = contains(Markerinput,"y","IgnoreCase",true);
            if CheckMarkers == 1
                TempMarker = 'o';
                HumMarker = 's';
            else
                TempMarker = 'none';
                HumMarker = 'none';
            end
            
            Colorinput1 = app.LineColorForTemperatureDropDown.Value;
            Colorinput2 = app.LineColorForHumidityDropDown.Value;

            TempLineColor = LineColor(app,Colorinput1);
            TempLineColor = char(TempLineColor);
            HumLineColor = LineColor(app,Colorinput2);
            HumLineColor = char(HumLineColor);
            
            PlotTempVal = app.PlotTemperatureSwitch.Value;
            if contains(PlotTempVal,"On") == 1
                plot(app.UIAxes,app.RecordedData(:,2),app.RecordedData(:,1),TempLineColor,TempMarker)
            end
            
            PlotHumVal = app.PlotHumiditySwitch.Value;
            if contains(PlotHumVal,"On") == 1
                plot(app.UIAxes,app.RecordedData(:,3),app.RecordedData(:,2),HumLineColor,HumMarker)
            end
            
            hold(app.UIAxes,'on')
         
            ylim(app.UIAxes,[0 60])
            hold off
            
        end

        % Button pushed function: SaveToExcelButton
        function SaveToExcelButtonPushed(app, event)
            
            Labels = ["Temperature" , "Humidity" , "Time"];
            xlswrite('Temerature_and_Humidity_Data.xlsx',Labels,'Sheet 1','B1:D1')
            xlsWrite('Temerature_and_Humidity_Data.xlsx',app.RecordedData(:,1),'Sheet 1','B1:B100')
            xlsWrite('Temerature_and_Humidity_Data.xlsx',app.RecordedData(:,2),'Sheet 1','C1:C100')
            xlsWrite('Temerature_and_Humidity_Data.xlsx',app.RecordedData(:,3),'Sheet 1','D1:D100')
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Temperature and Humidy over Time')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Temperature and Humidity')
            zlabel(app.UIAxes, 'Z')
            app.RecordingTimeKnobLabel = uilabel(app.UIFigure);
            app.RecordingTimeKnobLabel.Text = 'Recording Time';
            app.RecordingTime = uiknob(app.UIFigure, 'discrete');
            app.RecordingTime.Items = {'15', '30', '45', '60'};
            app.RecordingTime.Value = '15';
            app.StartRecordingButton = uibutton(app.UIFigure, 'push');
            app.StartRecordingButton.ButtonPushedFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.StartRecordingButton.Text = 'Start Recording';
            app.RecordingTimeProgressLabel = uilabel(app.UIFigure);
            app.RecordingTimeProgressLabel.Text = 'Recording Time Progress';
            app.RecordingTimeProgressGauge = uigauge(app.UIFigure, 'circular');
            app.Lamp = uilamp(app.UIFigure);
            app.CumulativeNumberOfRecordingsGaugeLabel = uilabel(app.UIFigure);
            app.CumulativeNumberOfRecordingsGaugeLabel.Text = 'Cumulative Number Of Recordings';
            app.CumulativeNumberOfRecordingsGauge = uigauge(app.UIFigure, 'circular');
            app.PlotHumiditySwitchLabel = uilabel(app.UIFigure);
            app.PlotHumiditySwitchLabel.Text = 'Plot Humidity';
            app.PlotHumiditySwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotTemperatureSwitchLabel = uilabel(app.UIFigure);
            app.PlotTemperatureSwitchLabel.Text = 'Plot Temperature';
            app.PlotTemperatureSwitch = uiswitch(app.UIFigure, 'slider');
            app.PlotDataButton = uibutton(app.UIFigure, 'push');
            app.PlotDataButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDataButtonPushed, true);
            app.PlotDataButton.Text = 'Plot Data';
            app.PlotwithmarkersEditFieldLabel = uilabel(app.UIFigure);
            app.PlotwithmarkersEditFieldLabel.Text = 'Plot with markers?';
            app.PlotwithmarkersEditField = uieditfield(app.UIFigure, 'text');
            app.SaveToExcelButton = uibutton(app.UIFigure, 'push');
            app.SaveToExcelButton.ButtonPushedFcn = createCallbackFcn(app, @SaveToExcelButtonPushed, true);
            app.SaveToExcelButton.Text = 'SaveToExcel';
            app.LineColorForTemperatureDropDownLabel = uilabel(app.UIFigure);
            app.LineColorForTemperatureDropDownLabel.Text = 'Line Color For Temperature';
            app.LineColorForTemperatureDropDown = uidropdown(app.UIFigure);
            app.LineColorForTemperatureDropDown.Items = {'Black', 'Red', 'Blue', 'Yellow'};
            app.LineColorForTemperatureDropDown.Value = 'Red';
            app.LineColorForHumidityLabel = uilabel(app.UIFigure);
            app.LineColorForHumidityLabel.Text = 'Line Color For Humidity';
            app.LineColorForHumidityDropDown = uidropdown(app.UIFigure);
            app.LineColorForHumidityDropDown.Items = {'Black', 'Red', 'Blue', 'Yellow'};
            app.LineColorForHumidityDropDown.Value = 'Blue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENGR131phase3lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end